classdef MyProjectToolBoxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AddButton            matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        ComprehensiveMATLABScriptsCollectionLabel  matlab.ui.control.Label
        ProjectToolboxLabel  matlab.ui.control.Label
        HelpButton           matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        ProjectToolboxv10ClickthumbnailstorunapplicationsLabel  matlab.ui.control.Label
        StatsLabel           matlab.ui.control.Label
        ActionLabel          matlab.ui.control.Label
        Image3               matlab.ui.control.Image
        EscapeButton         matlab.ui.control.Image
        SearchList           matlab.ui.control.ListBox
        SearchEditField      matlab.ui.control.EditField
        RunOpenAbout         matlab.ui.control.DropDown
        TabGroup             matlab.ui.container.TabGroup
        AllScriptsTab        matlab.ui.container.Tab
        GridLayout           matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        ExampleLabel         matlab.ui.control.Label
        ImportantTab         matlab.ui.container.Tab
        GridLayout_2         matlab.ui.container.GridLayout
        OthersTab_2          matlab.ui.container.Tab
    end

    properties (Access = public)
        scripts_registry
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.scripts_registry = readtable('scripts_registry.xlsx'); % Read excel
            app.StatsLabel.Text = sprintf('📊 %d Scripts Available', height(app.scripts_registry)); % Update stats
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
                [~, selectedScript , ~] = fileparts(event.Source.ImageSource);
                UserClicked(app, selectedScript); % Function
        end

        % Value changed function: SearchList
        function SearchResultClicked(app, event)
                selectedScript = app.SearchList.Value;
                if strcmp(selectedScript, 'No matches found') % Ignore if "No matches found"
                    return;
                end
                app.SearchList.Value = {};
                app.SearchList.Visible = 'off';
                UserClicked(app, selectedScript); % Function
        end

        % Button pushed function: HelpButton
        function Help(app, event)
            helpText = sprintf(['Welcome to the Project Toolbox!\n\n' ...
                '• Use the search bar to find scripts quickly\n' ...
                '• Select "Run" to execute a script\n' ...
                '• Select "Open" to edit the script\n' ...
                '• Select "About" to read script description\n\n' ...
                'Click on any figure to run script.']);
            uialert(app.UIFigure, helpText, 'Help', 'Icon', 'info');            
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            selectedScript = event.Value;
            if isempty(selectedScript) && strcmp(app.EscapeButton.Visible, 'off') % To fix an appdesigner bug
                return
            end
            matches = contains(app.scripts_registry.ScriptName, selectedScript, 'IgnoreCase', true);
            FilteredResults = app.scripts_registry(matches, :);
            if height(FilteredResults) == 0
                app.SearchList.Items = {'No matches found'}; % If no matches
            else
                app.SearchList.Items = FilteredResults.ScriptName; % Update SearchList with results
            end
            app.SearchList.Visible = 'on';
            app.EscapeButton.Visible = 'on';
        end

        % Image clicked function: EscapeButton
        function EscapeButtonClicked(app, event)
            app.SearchEditField.Value = '';
            app.SearchList.Visible = 'off';
            app.EscapeButton.Visible = 'off';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Open Excel file
            winopen('scripts_registry.xlsx');
            
            % Show instruction dialog
            msg = sprintf(['✅ Excel file opened!\n\n' ...
                '📝 INSTRUCTIONS:\n\n' ...
                '1. Add your scripts to the registry\n' ...
                '2. Save the Excel file\n' ...
                '3. Reload the App\n\n' ...
                '📋 Required columns:\n' ...
                '   • ScriptName\n' ...
                '   • Path\n' ...
                '   • About (optional)']);
            
            uialert(app.UIFigure, msg, '📝 Edit Registry', 'Icon', 'info');
            disp('5');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Toolbox App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Figures', 'AppSymbols', 'rocket.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AllScriptsTab = uitab(app.TabGroup);
            app.AllScriptsTab.Title = '📚 All Scripts';
            app.GridLayout = uigridlayout(app.AllScriptsTab);
            app.ExampleLabel = uilabel(app.GridLayout);
            app.ExampleLabel.Text = 'Example';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Figures', 'Example.png');
            app.ImportantTab = uitab(app.TabGroup);
            app.ImportantTab.Title = '⭐ Important';
            app.GridLayout_2 = uigridlayout(app.ImportantTab);
            app.OthersTab_2 = uitab(app.TabGroup);
            app.OthersTab_2.Title = '📁 Others';
            app.RunOpenAbout = uidropdown(app.UIFigure);
            app.RunOpenAbout.Items = {'▶ Run', '📝 Open', 'ℹ About'};
            app.RunOpenAbout.ItemsData = {'Run', 'Open', 'About'};
            app.RunOpenAbout.Value = 'Run';
            app.SearchEditField = uieditfield(app.UIFigure, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.SearchList = uilistbox(app.UIFigure);
            app.SearchList.Items = {};
            app.SearchList.ValueChangedFcn = createCallbackFcn(app, @SearchResultClicked, true);
            app.SearchList.Visible = 'off';
            app.SearchList.Value = {};
            app.EscapeButton = uiimage(app.UIFigure);
            app.EscapeButton.ImageClickedFcn = createCallbackFcn(app, @EscapeButtonClicked, true);
            app.EscapeButton.Visible = 'off';
            app.EscapeButton.ImageSource = fullfile(pathToMLAPP, 'Figures', 'AppSymbols', 'Escape.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Figures', 'AppSymbols', 'icons8-search-48.png');
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = 'Action:';
            app.Panel = uipanel(app.UIFigure);
            app.StatsLabel = uilabel(app.Panel);
            app.StatsLabel.Text = 'XX Scripts available';
            app.ProjectToolboxv10ClickthumbnailstorunapplicationsLabel = uilabel(app.Panel);
            app.ProjectToolboxv10ClickthumbnailstorunapplicationsLabel.Text = '© 2025 Project Toolbox | v1.0 | Click thumbnails to run applications';
            app.Panel_2 = uipanel(app.UIFigure);
            app.HelpButton = uibutton(app.Panel_2, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @Help, true);
            app.HelpButton.Text = '?';
            app.ProjectToolboxLabel = uilabel(app.Panel_2);
            app.ProjectToolboxLabel.Text = 'Project Toolbox';
            app.ComprehensiveMATLABScriptsCollectionLabel = uilabel(app.Panel_2);
            app.ComprehensiveMATLABScriptsCollectionLabel.Text = 'Comprehensive MATLAB Scripts Collection';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+ Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyProjectToolBoxApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end